classdef Create_data_structures < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CreatedatastructuresUIFigure   matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CreatenewdatastructureTab      matlab.ui.container.Tab
        FilltheformtocreatethedatastructurePanel  matlab.ui.container.Panel
        DatamatfilepathEditFieldLabel  matlab.ui.control.Label
        dataPath                       matlab.ui.control.EditField
        NumberofsensorsEditFieldLabel  matlab.ui.control.Label
        numberOfSensors                matlab.ui.control.NumericEditField
        browseButton_1                 matlab.ui.control.Button
        UnitsspecificationPanel        matlab.ui.container.Panel
        DatatimeunitsLabel             matlab.ui.control.Label
        timeStepUnits                  matlab.ui.control.DropDown
        DatatimestepinunitsaboveLabel  matlab.ui.control.Label
        dataTimeStep                   matlab.ui.control.NumericEditField
        timeUnitsLable                 matlab.ui.control.Label
        FlowunitsLabel                 matlab.ui.control.Label
        flowUnits                      matlab.ui.control.DropDown
        LevelunitsLabel                matlab.ui.control.Label
        levelUnits                     matlab.ui.control.DropDown
        converttoLabel                 matlab.ui.control.Label
        convertedFlowUnits             matlab.ui.control.DropDown
        converttoLabel_2               matlab.ui.control.Label
        convertedLevelUnits            matlab.ui.control.DropDown
        SelectmodeltypeLabel           matlab.ui.control.Label
        modelSelector                  matlab.ui.control.DropDown
        boundaryCondition              matlab.ui.container.ButtonGroup
        free_flow                      matlab.ui.control.RadioButton
        submerged_flow                 matlab.ui.control.RadioButton
        InitialparametersEditFieldLabel  matlab.ui.control.Label
        initialParameters              matlab.ui.control.EditField
        makeDataStructure              matlab.ui.control.Button
        StructurenameLabel             matlab.ui.control.Label
        structureName                  matlab.ui.control.EditField
        lateralInflow                  matlab.ui.control.CheckBox
        CheckifthelateralflowisincludedLabel  matlab.ui.control.Label
        NumberofaugmentedstatesEditFieldLabel  matlab.ui.control.Label
        numberOfAugmentedStates        matlab.ui.control.EditField
        UseexistingdatastructureTab    matlab.ui.container.Tab
        ExistingstructurenameLabel     matlab.ui.control.Label
        useExistingStructure           matlab.ui.control.EditField
        useButton                      matlab.ui.control.Button
        TOBEIMPLEMENTEDLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        fileNameProperty % Description
        filePathProperty % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function app_open_fcn(app)
            path = convertCharsToStrings(erase(mfilename('fullpath'),append('\',mfilename)));
            addpath(path);
        end

        % Button pushed function: browseButton_1
        function browse1Callback(app, event)
            [file,path] = uigetfile('*.mat');  %open a mat file
            app.fileNameProperty = file;
            app.filePathProperty = path;
            app.dataPath.Value = fullfile(path, file); 
        end

        % Value changed function: timeStepUnits
        function timeUnits_ValueChange(app, event)
            value = app.timeStepUnits.Value;
            app.timeUnitsLable.Text = value;
        end

        % Button pushed function: makeDataStructure
        function make_data_structure(app, event)
            savePath = append(uigetdir(app.filePathProperty),'\');
            if contains(app.fileNameProperty, ".mat") 
                data = cell2mat(struct2cell(load(app.dataPath.Value)));
            elseif contains(app.fileNameProperty, ".csv")
                data = readmatrix(app.dataPath.Value);
            end
            if(app.free_flow.Value)
                boundary_equation = 'free_flow';
            end
            if(app.submerged_flow.Value)
                boundary_equation = 'submerged_flow';
            end
            dataStructure = struct('data',data,'number_of_sensors',app.numberOfSensors.Value,'number_of_augmented_states',str2num(app.numberOfAugmentedStates.Value),'data_time_unit',app.timeStepUnits.Value,'data_time_step',app.dataTimeStep.Value,'flow_units',app.flowUnits.Value,'flow_convert_to', app.convertedFlowUnits.Value ,'level_units',app.levelUnits.Value,'level_convert_to',app.convertedLevelUnits.Value ,'model_type',app.modelSelector.Value, 'lateral_inflow', app.lateralInflow.Value,'boundary_condition',boundary_equation,'initial_parameters',str2num(app.initialParameters.Value));
            save(append(savePath,  app.structureName.Value),"dataStructure");
            f = msgbox('Data Structure sucesfully created!','OK!');
        end

        % Callback function
        function load_init_params(app, event)
            
        end

        % Value changed function: flowUnits
        function flow_units_change(app, event)
            value = app.flowUnits.Value;
            app.convertedFlowUnits.Value = value;
        end

        % Value changed function: levelUnits
        function level_units_change(app, event)
            value = app.levelUnits.Value;
            app.convertedLevelUnits.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CreatedatastructuresUIFigure = uifigure('Visible', 'off');
            app.CreatedatastructuresUIFigure.Name = 'Create data structures';
            app.TabGroup = uitabgroup(app.CreatedatastructuresUIFigure);
            app.CreatenewdatastructureTab = uitab(app.TabGroup);
            app.CreatenewdatastructureTab.Title = 'Create new data structure';
            app.FilltheformtocreatethedatastructurePanel = uipanel(app.CreatenewdatastructureTab);
            app.FilltheformtocreatethedatastructurePanel.Title = 'Fill the form to create the data structure';
            app.DatamatfilepathEditFieldLabel = uilabel(app.FilltheformtocreatethedatastructurePanel);
            app.DatamatfilepathEditFieldLabel.Text = {'Data .mat file path:'; ''};
            app.dataPath = uieditfield(app.FilltheformtocreatethedatastructurePanel, 'text');
            app.dataPath.Tag = 'dataPath';
            app.NumberofsensorsEditFieldLabel = uilabel(app.FilltheformtocreatethedatastructurePanel);
            app.NumberofsensorsEditFieldLabel.Text = 'Number of sensors:';
            app.numberOfSensors = uieditfield(app.FilltheformtocreatethedatastructurePanel, 'numeric');
            app.numberOfSensors.Value = 4;
            app.browseButton_1 = uibutton(app.FilltheformtocreatethedatastructurePanel, 'push');
            app.browseButton_1.ButtonPushedFcn = createCallbackFcn(app, @browse1Callback, true);
            app.browseButton_1.Tag = 'browseButton_1';
            app.browseButton_1.Text = 'Browse';
            app.UnitsspecificationPanel = uipanel(app.FilltheformtocreatethedatastructurePanel);
            app.UnitsspecificationPanel.Title = 'Units specification';
            app.DatatimeunitsLabel = uilabel(app.UnitsspecificationPanel);
            app.DatatimeunitsLabel.Text = 'Data time units:';
            app.timeStepUnits = uidropdown(app.UnitsspecificationPanel);
            app.timeStepUnits.Items = {'s', 'min', 'h'};
            app.timeStepUnits.ValueChangedFcn = createCallbackFcn(app, @timeUnits_ValueChange, true);
            app.timeStepUnits.Value = 's';
            app.DatatimestepinunitsaboveLabel = uilabel(app.UnitsspecificationPanel);
            app.DatatimestepinunitsaboveLabel.Text = 'Data time step (in units above):';
            app.dataTimeStep = uieditfield(app.UnitsspecificationPanel, 'numeric');
            app.dataTimeStep.Value = 1;
            app.timeUnitsLable = uilabel(app.UnitsspecificationPanel);
            app.timeUnitsLable.Text = 's';
            app.FlowunitsLabel = uilabel(app.UnitsspecificationPanel);
            app.FlowunitsLabel.Text = 'Flow units:';
            app.flowUnits = uidropdown(app.UnitsspecificationPanel);
            app.flowUnits.Items = {'L/s', 'L/min', 'm^3/s', 'mm^3/s'};
            app.flowUnits.ValueChangedFcn = createCallbackFcn(app, @flow_units_change, true);
            app.flowUnits.Value = 'm^3/s';
            app.LevelunitsLabel = uilabel(app.UnitsspecificationPanel);
            app.LevelunitsLabel.Text = 'Level units:';
            app.levelUnits = uidropdown(app.UnitsspecificationPanel);
            app.levelUnits.Items = {'mm', 'cm', 'dm', 'm'};
            app.levelUnits.ValueChangedFcn = createCallbackFcn(app, @level_units_change, true);
            app.levelUnits.Value = 'm';
            app.converttoLabel = uilabel(app.UnitsspecificationPanel);
            app.converttoLabel.Text = ', convert to:';
            app.convertedFlowUnits = uidropdown(app.UnitsspecificationPanel);
            app.convertedFlowUnits.Items = {'L/s', 'L/min', 'm^3/s', 'mm^3/s'};
            app.convertedFlowUnits.Value = 'm^3/s';
            app.converttoLabel_2 = uilabel(app.UnitsspecificationPanel);
            app.converttoLabel_2.Text = ', convert to:';
            app.convertedLevelUnits = uidropdown(app.UnitsspecificationPanel);
            app.convertedLevelUnits.Items = {'mm', 'cm', 'dm', 'm'};
            app.convertedLevelUnits.Value = 'm';
            app.SelectmodeltypeLabel = uilabel(app.FilltheformtocreatethedatastructurePanel);
            app.SelectmodeltypeLabel.Text = 'Select model type:';
            app.modelSelector = uidropdown(app.FilltheformtocreatethedatastructurePanel);
            app.modelSelector.Items = {'NL Kinematic wave', 'NL Diffusion wave', 'Linear Diffusion Wave'};
            app.modelSelector.Value = 'Linear Diffusion Wave';
            app.boundaryCondition = uibuttongroup(app.FilltheformtocreatethedatastructurePanel);
            app.boundaryCondition.Title = 'Boundary condition:';
            app.free_flow = uiradiobutton(app.boundaryCondition);
            app.free_flow.Text = 'Free overflow';
            app.free_flow.Value = true;
            app.submerged_flow = uiradiobutton(app.boundaryCondition);
            app.submerged_flow.Text = 'Submerged flow';
            app.InitialparametersEditFieldLabel = uilabel(app.FilltheformtocreatethedatastructurePanel);
            app.InitialparametersEditFieldLabel.Text = 'Initial parameters:';
            app.initialParameters = uieditfield(app.FilltheformtocreatethedatastructurePanel, 'text');
            app.initialParameters.Tooltip = {'Define as a vector of #parameters size. Default AAU waterlab params are defined.'};
            app.initialParameters.Value = '[0.0367815377915248	0.0546308635442872	0.00685599772690394	-0.00209121684418040	0.0365705383994406]';
            app.makeDataStructure = uibutton(app.FilltheformtocreatethedatastructurePanel, 'push');
            app.makeDataStructure.ButtonPushedFcn = createCallbackFcn(app, @make_data_structure, true);
            app.makeDataStructure.Text = {'Create data '; 'structure'};
            app.StructurenameLabel = uilabel(app.FilltheformtocreatethedatastructurePanel);
            app.StructurenameLabel.Text = 'Structure name:';
            app.structureName = uieditfield(app.FilltheformtocreatethedatastructurePanel, 'text');
            app.structureName.Tag = 'dataPath';
            app.lateralInflow = uicheckbox(app.FilltheformtocreatethedatastructurePanel);
            app.lateralInflow.Text = 'Lateral inflow';
            app.CheckifthelateralflowisincludedLabel = uilabel(app.FilltheformtocreatethedatastructurePanel);
            app.CheckifthelateralflowisincludedLabel.Text = 'Check if the lateral flow is included:';
            app.NumberofaugmentedstatesEditFieldLabel = uilabel(app.FilltheformtocreatethedatastructurePanel);
            app.NumberofaugmentedstatesEditFieldLabel.Text = {'Number of augmented states:'; ''};
            app.numberOfAugmentedStates = uieditfield(app.FilltheformtocreatethedatastructurePanel, 'text');
            app.numberOfAugmentedStates.Tooltip = {'Define as a vector of size #sensors+1.'; ''};
            app.numberOfAugmentedStates.Value = '[0,0,0,0,0]';
            app.UseexistingdatastructureTab = uitab(app.TabGroup);
            app.UseexistingdatastructureTab.Title = 'Use existing data structure';
            app.ExistingstructurenameLabel = uilabel(app.UseexistingdatastructureTab);
            app.ExistingstructurenameLabel.Text = {'Existing structure name:'; ''};
            app.useExistingStructure = uieditfield(app.UseexistingdatastructureTab, 'text');
            app.useButton = uibutton(app.UseexistingdatastructureTab, 'push');
            app.useButton.Text = 'Use';
            app.TOBEIMPLEMENTEDLabel = uilabel(app.UseexistingdatastructureTab);
            app.TOBEIMPLEMENTEDLabel.Text = 'TO BE IMPLEMENTED!';
            % Show the figure after all components are created
            app.CreatedatastructuresUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Create_data_structures

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CreatedatastructuresUIFigure)

            % Execute the startup function
            runStartupFcn(app, @app_open_fcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CreatedatastructuresUIFigure)
        end
    end
end